classdef appsec02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CompressionRatioSlider       matlab.ui.control.Slider
        CompressionRatioSliderLabel  matlab.ui.control.Label
        DirectoryEditField           matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        OpenButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            inputDir=uigetdir;
            pngFiles = dir(strcat(inputDir,'\*.png'));
            pngFiles.folder
            
            outvideo = VideoWriter('com\video5.avi');
            outvideo.FrameRate = 1;
            open(outvideo);
            % 
            count = length(pngFiles);
            pause(1);
            prog = waitbar(0,"wait");
            for i = 1:count
                
                frame = imread(strcat( pngFiles(i).folder,'\', pngFiles(i).name));
                writeVideo(outvideo,frame);
                waitbar(i/count,prog,"Processing");
                app.Image.ImageSource = frame;
                pause(1);
            %     imshow(frame);
                
            end
            waitbar(1,prog,"done");
            close(prog);
            close(outvideo);
        end

        % Value changed function: CompressionRatioSlider
        function CompressionRatioSliderValueChanged(app, event)
            value = app.CompressionRatioSlider.Value;
            app.DirectoryEditField.Value = int2str (value);
        end

        % Value changing function: CompressionRatioSlider
        function CompressionRatioSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DirectoryEditField.Value = int2str (changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'folder.png';
            app.OpenButton.Text = 'Open';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'before.png';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.CompressionRatioSliderLabel = uilabel(app.UIFigure);
            app.CompressionRatioSliderLabel.Text = 'Compression Ratio';
            app.CompressionRatioSlider = uislider(app.UIFigure);
            app.CompressionRatioSlider.Limits = [4 16];
            app.CompressionRatioSlider.MajorTicks = [4 8 16];
            app.CompressionRatioSlider.ValueChangedFcn = createCallbackFcn(app, @CompressionRatioSliderValueChanged, true);
            app.CompressionRatioSlider.ValueChangingFcn = createCallbackFcn(app, @CompressionRatioSliderValueChanging, true);
            app.CompressionRatioSlider.MinorTicks = [];
            app.CompressionRatioSlider.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appsec02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end