classdef appsec1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CompressionselectionEditField  matlab.ui.control.EditField
        CompressionselectionEditFieldLabel  matlab.ui.control.Label
        CompressionRatioSlider         matlab.ui.control.Slider
        CompressionRatioSliderLabel    matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        BrowseButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            inputDir=uigetdir;
            inputDir;
            pngFiles = dir(strcat(inputDir,'\*.png'));
            pngFiles.folder
            
            outvideo = VideoWriter('com\video5.avi');
            outvideo.FrameRate = 1;
            open(outvideo);
            pause(1);
            Pro= waitbar(0,"wait");
            % 
            count = length(pngFiles);
            for i = 1:count
                
                frame = imread(strcat( pngFiles(i).folder,'\', pngFiles(i).name));
                app.Image.ImageSource = frame;
                writeVideo(outvideo,frame);
                
                waitbar(i/count,Pro,"wait");
                pause(1);
            %     imshow(frame);
                
            end
            
            
            close(outvideo);
        end

        % Value changed function: CompressionRatioSlider
        function CompressionRatioSliderValueChanged(app, event)
            value = app.CompressionRatioSlider.Value;
            app.CompressionselectionEditField.Value = int2str(value);
        end

        % Value changing function: CompressionRatioSlider
        function CompressionRatioSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CompressionselectionEditField.Value = int2str(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'folder.png';
            app.BrowseButton.Text = 'Browse';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'before.png';
            app.CompressionRatioSliderLabel = uilabel(app.UIFigure);
            app.CompressionRatioSliderLabel.Text = 'Compression Ratio';
            app.CompressionRatioSlider = uislider(app.UIFigure);
            app.CompressionRatioSlider.Limits = [4 16];
            app.CompressionRatioSlider.MajorTicks = [4 8 16];
            app.CompressionRatioSlider.ValueChangedFcn = createCallbackFcn(app, @CompressionRatioSliderValueChanged, true);
            app.CompressionRatioSlider.ValueChangingFcn = createCallbackFcn(app, @CompressionRatioSliderValueChanging, true);
            app.CompressionRatioSlider.Value = 4;
            app.CompressionselectionEditFieldLabel = uilabel(app.UIFigure);
            app.CompressionselectionEditFieldLabel.Text = 'Compression selection';
            app.CompressionselectionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appsec1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end