classdef comapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        comvalueEditField       matlab.ui.control.EditField
        comvalueEditFieldLabel  matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        OpenButton              matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OpenButton
        function OpenButtonValueChanged(app, event)
            value = app.OpenButton.Value;
            inputdir = uigetdir;
            pngFiles = dir(strcat(inputdir,'\*.png'));
            pngFiles.name
            
            outVideo = VideoWriter('com\video.avi');
            outVideo.FrameRate = 1;
            open(outVideo);
            len = length(pngFiles);
            f= waitbar(0,"Pleae wait");
            for i=1:length(pngFiles) 
                frame = imread(strcat(pngFiles(i).folder,'\',pngFiles(i).name));
                app.Image.ImageSource = frame;
                writeVideo(outVideo, frame);
                waitbar((i/len),f,"Pleae wait");
                pause(1);
            end
            
            close(outVideo);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.comvalueEditField.Value = int2str(value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.comvalueEditField.Value = int2str(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenButton = uibutton(app.UIFigure, 'state');
            app.OpenButton.ValueChangedFcn = createCallbackFcn(app, @OpenButtonValueChanged, true);
            app.OpenButton.Text = 'Open';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'back.png';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.comvalueEditFieldLabel = uilabel(app.UIFigure);
            app.comvalueEditFieldLabel.Text = 'com value';
            app.comvalueEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = comapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end